classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        MainMenu       matlab.ui.container.Menu
        StartMenu      matlab.ui.container.Menu
        QuitMenu       matlab.ui.container.Menu
        Label_7        matlab.ui.control.Label
        Label_5        matlab.ui.control.Label
        Label_2        matlab.ui.control.Label
        Label_3        matlab.ui.control.Label
        Label_4        matlab.ui.control.Label
        Label          matlab.ui.control.Label
        UserEditField  matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: StartMenu
        function trainnetwork(app, event)
                run("training.m")
                run("greetings.m")
                app.Label.Text="Bot:  "+first;
                app.Label.WordWrap = 'on';
                global i;
                i=1;
        end

        % Value changed function: UserEditField
        function userinput(app, event)
            global flagGo;
            global i;
            value = app.UserEditField.Value;
                [flagGo, responce]=responces(value );
                switch(i)
                    case 1
                    app.Label_2.Text="User:  "+value;
                    app.Label_3.Text="Bot:  "+responce;
                    app.Label_3.WordWrap = 'on';
                    i=2;
                    case 2
                    app.Label_4.Text="User:  "+value;
                    app.Label_5.Text="Bot:  "+responce;
                    app.Label_5.WordWrap = 'on';
                    i=3;
                    case 3
                    app.Label_7.Text="User:  "+value; 
                    app.Label.Text="Bot:  "+responce;
                    app.Label.WordWrap = 'on'; 
                    app.Label_2.Text='';
                    app.Label_3.Text='';
                    app.Label_4.Text='';
                    app.Label_5.Text='';
                    i=1;
                        
                end 
                    app.UserEditField.Value='';
                    if(~flagGo)
                        delete(app)
                    end
                
              
        end

        % Menu selected function: QuitMenu
        function quit(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainMenu = uimenu(app.UIFigure);
            app.MainMenu.Text = 'Main';
            app.StartMenu = uimenu(app.MainMenu);
            app.StartMenu.MenuSelectedFcn = createCallbackFcn(app, @trainnetwork, true);
            app.StartMenu.Text = 'Start';
            app.QuitMenu = uimenu(app.MainMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @quit, true);
            app.QuitMenu.Text = 'Quit';
            app.UserEditField = uieditfield(app.UIFigure, 'text');
            app.UserEditField.ValueChangedFcn = createCallbackFcn(app, @userinput, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {''; ''};
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end